classdef MEMStepper_DAC_Approach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MEMSStepperControlUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MEMSReadoutPanel                matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        ApproachMaxVEditField           matlab.ui.control.NumericEditField
        ApproachMaxVEditFieldLabel      matlab.ui.control.Label
        Vstepapproach                   matlab.ui.control.NumericEditField
        AppoachStepVEditFieldLabel      matlab.ui.control.Label
        ApproachZButton                 matlab.ui.control.Button
        ReadprobeCheckBox               matlab.ui.control.CheckBox
        ReadcapCheckBox                 matlab.ui.control.CheckBox
        SetVprobeButton                 matlab.ui.control.Button
        ProbeVoltageEditField           matlab.ui.control.NumericEditField
        WaitTimescaponlyEditField       matlab.ui.control.NumericEditField
        WaitTimescaponlyEditFieldLabel  matlab.ui.control.Label
        SetVdButton                     matlab.ui.control.Button
        VdField                         matlab.ui.control.NumericEditField
        ConnectLockinButton             matlab.ui.control.Button
        LockinDropDown                  matlab.ui.control.DropDown
        SR860Label                      matlab.ui.control.Label
        CapacitiveMotionDetectionPanel  matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        ProbeLabel                      matlab.ui.control.Label
        ProbeValue                      matlab.ui.control.Label
        ReadoutLabel                    matlab.ui.control.Label
        CapValue                        matlab.ui.control.Label
        StartStopCapButton              matlab.ui.control.Button
        ClearCapButton                  matlab.ui.control.Button
        AxesCapFitting                  matlab.ui.control.UIAxes
        AxesCapReadout                  matlab.ui.control.UIAxes
        GridLayout6                     matlab.ui.container.GridLayout
        RecordMovieButton               matlab.ui.control.Button
        StartPreviewButton              matlab.ui.control.Button
        ContinuousButton                matlab.ui.control.Button
        SnapshotButton                  matlab.ui.control.Button
        StatusPanel                     matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        VzGauge                         matlab.ui.control.SemicircularGauge
        VzGaugeLabel                    matlab.ui.control.Label
        V1Gauge                         matlab.ui.control.SemicircularGauge
        V1GaugeLabel                    matlab.ui.control.Label
        V3Gauge                         matlab.ui.control.SemicircularGauge
        V3GaugeLabel                    matlab.ui.control.Label
        V2Gauge                         matlab.ui.control.SemicircularGauge
        V2GaugeLabel                    matlab.ui.control.Label
        MEMScontrolPanel                matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        ConnectDACButton                matlab.ui.control.Button
        VzField                         matlab.ui.control.NumericEditField
        ZeroVzButton                    matlab.ui.control.Button
        DACLabel                        matlab.ui.control.Label
        DACDropDown                     matlab.ui.control.DropDown
        FlashVzButton                   matlab.ui.control.Button
        SetVzButton                     matlab.ui.control.Button
        OUTPUTOFFButton                 matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        DirectTab                       matlab.ui.container.Tab
        V1Label                         matlab.ui.control.NumericEditField
        V2Label                         matlab.ui.control.NumericEditField
        V3Label                         matlab.ui.control.NumericEditField
        V3KnobLabel                     matlab.ui.control.Label
        V3Knob                          matlab.ui.control.Knob
        V2KnobLabel                     matlab.ui.control.Label
        V2Knob                          matlab.ui.control.Knob
        V1KnobLabel                     matlab.ui.control.Label
        V1Knob                          matlab.ui.control.Knob
        SetButton                       matlab.ui.control.Button
        SteppingTab                     matlab.ui.container.Tab
        SyncCapFittingCheckBox          matlab.ui.control.CheckBox
        MinusQuarterButton              matlab.ui.control.Button
        PlusQuarterButton               matlab.ui.control.Button
        MinusHalfButton                 matlab.ui.control.Button
        PlusHalfButton                  matlab.ui.control.Button
        Minus18Button                   matlab.ui.control.Button
        ZeroStepsButton                 matlab.ui.control.Button
        StepsEditField                  matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        PhaseEditField                  matlab.ui.control.NumericEditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        DriveVoltageLabel               matlab.ui.control.Label
        DriveVoltageEditField           matlab.ui.control.NumericEditField
        Plus18Button                    matlab.ui.control.Button
        InitButton                      matlab.ui.control.Button
        PositioningTab                  matlab.ui.container.Tab
        MicrostepDropDown               matlab.ui.control.DropDown
        MicrostepDropDownLabel          matlab.ui.control.Label
        GOButton                        matlab.ui.control.Button
        SpeedstepsEditFieldLabel        matlab.ui.control.Label
        SpeedstepsEditField             matlab.ui.control.NumericEditField
        StepsEditField_2                matlab.ui.control.NumericEditField
        StepsEditField_2Label           matlab.ui.control.Label
        TargetStepEditFieldLabel        matlab.ui.control.Label
        TargetStepEditField             matlab.ui.control.NumericEditField
        InitResetButton                 matlab.ui.control.Button
        DriveVoltageEditField_2Label    matlab.ui.control.Label
        DriveVoltageEditField_2         matlab.ui.control.NumericEditField
        ImageMotionDetectionPanel       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ClearGraphsButton               matlab.ui.control.Button
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        CameraControlTab                matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        MicroscopeObjectiveDropDown     matlab.ui.control.DropDown
        MicroscopeObjectiveDropDownLabel  matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        ResolutionLabel                 matlab.ui.control.Label
        Resolution                      matlab.ui.control.DropDown
        GridLayout7                     matlab.ui.container.GridLayout
        DeviceLabel                     matlab.ui.control.Label
        DeviceName                      matlab.ui.control.DropDown
        GainSlider                      matlab.ui.control.Slider
        GainSliderLabel                 matlab.ui.control.Label
        ExposureSliderLabel             matlab.ui.control.Label
        ExposureSlider                  matlab.ui.control.Slider
        MotionDetectionTab              matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        radialpitchumEditField          matlab.ui.control.NumericEditField
        radialpitchumEditFieldLabel     matlab.ui.control.Label
        AlignpeakCheckBox               matlab.ui.control.CheckBox
        ChooseradialrangeButton         matlab.ui.control.Button
        ChoosethetarangeButton          matlab.ui.control.Button
        PickcircumferenceButton         matlab.ui.control.Button
        MiscTab                         matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        MinScaleEditField               matlab.ui.control.NumericEditField
        MinScaleEditFieldLabel          matlab.ui.control.Label
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        GridLayout12                    matlab.ui.container.GridLayout
        VariableNameEditField           matlab.ui.control.EditField
        VarNameLabel                    matlab.ui.control.Label
        PreviewRefDesignButton          matlab.ui.control.Button
        AlignsnapshottoRefDesignExperimentalButton  matlab.ui.control.Button
        GridLayout11                    matlab.ui.container.GridLayout
        ReferenceDesignEditFieldLabel   matlab.ui.control.Label
        ReferenceDesignEditField        matlab.ui.control.EditField
        PickButton                      matlab.ui.control.Button
        SavesnapshottofileButton        matlab.ui.control.Button
        SyncSnapshotinworkspaceCheckBox  matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        video % Videoinput object
        hImage
        hImage2
        res   % Video resolution
        C     % Center
        snapshot % One frame
        rect_th  % selected rectangle for theta detection
        rect_r % selected rectangle for radial detection
        LockIn  % SR860 visa object
        DAC     % DAC visa object
        Vdac    % Current voltage on DAC
        
        X
        Y
        THETA
        R
        Xi
        Yi
        
        THETAth
        Rth
        Xth
        Yth
        
        disp_th % theta displacement, in degrees
        disp_r  % radial displacement, in um
        
        phase_th % theta displacement, in degrees
        phase_r  % radial displacement, in um
        
        refImg % 
        vw % VideoWriter

        volt_amp_ratio % voltage amplification ratio

        tim % Timer for capacitance readout
        capData % Cap readout data
        capPlot % Cap readout plot
        capFitData % Cap fitting data
    end


    methods (Static)
        function v=vphase(phase, shift)
            phase = mod(phase-shift, 2*pi);
            v = zeros(size(phase));
            v(phase<=4*pi/3) = -sin(phase(phase<=4*pi/3)*1.5);
            v(phase<=pi) = 1;
            v(phase<=pi/3) = sin(phase(phase<=pi/3)*1.5);
        end
    end

    methods (Access = private)
    
        function updateAvailableDevices(app)
            info = imaqhwinfo('winvideo');
            app.DeviceName.Items = arrayfun(@(x) x.DeviceName, info.DeviceInfo,'UniformOutput',false);
            app.DeviceName.ItemsData = arrayfun(@(x) x, info.DeviceInfo, 'UniformOutput', false);
        end
        
        function updateAvailableResolution(app)
            if ~isempty(app.DeviceName.Value)
                app.Resolution.Items = app.DeviceName.Value.SupportedFormats;
                app.Resolution.Value = app.DeviceName.Value.DefaultFormat;
                app.Resolution.UserData = app.DeviceName.Value;
            end
        end
        
        function cameraConfig(app, exp, gain)
            
            prev=imaqfind();
            while ~isempty(prev)
                stop(prev(1));
                stoppreview(prev(1));
                delete(prev(1));
                prev=prev(2:end);
            end
            app.Resolution.Value
            app.video = videoinput('winvideo', app.Resolution.UserData.DeviceID, app.Resolution.Value);
            src = getselectedsource(app.video);
            
            app.video.FramesPerTrigger = 1;
            app.video.TriggerRepeat = Inf;
            
            try
                if isprop(src, 'ExposureMode')
                    src.ExposureMode = 'manual';
                end
                if isprop(src, 'Exposure')
                    src.Exposure = exp;
                end
                if isprop(src, 'GainMode')
                    src.GainMode = 'manual';
                end
                if isprop(src, 'Gain')
                    src.Gain = gain;
                end
                if isprop(src, 'GammaMode')
                    src.GammaMode = 'manual';
                end
                if isprop(src, 'Gamma')
                    src.Gamma = 50;
                end
            catch
            end
        end
    
        function cameraStartPreview(app)
            app.res = app.video.VideoResolution;
            if isempty(app.hImage)
                app.hImage=image(app.UIAxes, zeros(fliplr(app.res)));
            end
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, [1, app.res(1), 1, app.res(2)])
            preview(app.video, app.hImage);
        end
        
        function cameraStopPreview(app)
            if isa(app.video, 'videoinput')
                stoppreview(app.video);
            end
            app.video=[];
        end
        
        function scanUSBPort(app)
            visa = visadevlist;
            app.LockinDropDown.Items = table2array(visa(:,1))';
            for i=size(visa,1):-1:1
                if ~strcmp(table2array(visa(i,4)), "SR860")
                    app.LockinDropDown.Items(i) = [];
                end
            end
        end


        function scanCOMPort(app)
            app.DACDropDown.Items = serialportlist;
        end
        
        function prepare(app)
            [app.X,app.Y]=ndgrid(1:app.res(1), 1:app.res(2)); 
            [t,r]=cart2pol(app.X-app.C(1),app.Y-app.C(2));
            [app.THETA, app.R]=ndgrid(linspace(min(t(:)), max(t(:)), 501), linspace(min(r(:)), max(r(:)), 601));
            [app.Xi, app.Yi]=pol2cart(app.THETA, app.R);
            
            % Prepare Axes2
            cla(app.UIAxes2);
            app.hImage2=imagesc(app.UIAxes2, app.THETA(:,1), app.R(1,:), zeros(size(app.THETA))');
            set(app.UIAxes2, 'YDir', 'normal');
            axis(app.UIAxes2, 'tight');
        end
        
        
        function process(app,f)
            if length(app.C)~=2
                return;
            end
            
            if size(f,3)~=1
                f=rgb2gray(f);
            end
            
            finterp = interp2(app.X'-app.C(1), app.Y'-app.C(2), double(f), app.Xi, app.Yi);
%             h=pcolor(app.UIAxes2, app.THETA, app.R, finterp);
%             set(h, 'EdgeColor', 'none');
            app.hImage2.CData = finterp';
            
%             if length(app.rsel)==2 && all(app.rsel>=app.R(1,1)) && all(app.rsel<=app.R(1,end))
            if isa(app.rect_th, 'images.roi.Rectangle') && ~isempty(app.rect_th.Position)
                % Detect theta movement
                rect = app.rect_th.Position;
                rect(3:4)=rect(1:2)+rect(3:4); % [xmin,ymin,xmax,ymax]
            
                [~,ind1]=min(abs(app.THETA(:,1)-rect(1)));
                [~,ind2]=min(abs(app.THETA(:,1)-rect(3)));
                [~,ind3]=min(abs(app.R(1,:)-rect(2)));
                [~,ind4]=min(abs(app.R(1,:)-rect(4)));
                
                f2 = finterp(ind1:ind2, ind3:ind4);
                Iavg = mean(f2, 2);
                
                th = app.THETA(~isnan(Iavg), 1);
                iavg = Iavg(~isnan(Iavg));
                
                if length(iavg)>4
                
                    plot(app.UIAxes3, th*180/pi, iavg, '.-');
                    axis(app.UIAxes3, 'tight');
                    
                    ift = fftshift(fft(iavg));
                    
                    [~,ind]=sort(abs(ift),'descend');
                    phase = angle(ift(ind(2)));
                    freq = abs(ind(3)-ind(2))/2*1/(th(2)-th(1))/length(th);
                    [~,ind]=max(iavg);
                    peak=th(ind);
                    
                                    
                    app.phase_th(end+1) = phase;
                    
                    % Convert to actual angle
                    if app.AlignpeakCheckBox.Value
                        if isempty(app.disp_th)
                            % First point, define as same as peak
                            theta = peak;
                        else
                            % peak + (phase-phase0)/2/pi/freq
                            % Select the same branch as the first point
                            tt = phase + app.disp_th(1)/180*pi*2*pi*freq - app.phase_th(1); % Shifted phase
                            theta = peak + (mod(tt - peak*2*pi*freq + pi, 2*pi) - pi) / (2*pi*freq); % Make sure it's in same winding as peak
                        end
                    else
                        theta = (mod(phase+pi,2*pi)-pi)/(2*pi*freq);
                    end
                    app.disp_th(end+1) = theta/pi*180; % Convert to degrees
                    
                    % Plot
                    plot(app.UIAxes5, app.disp_th);
                end
            end
            
            % Radial extraction
            if isa(app.rect_r, 'images.roi.Rectangle') && ~isempty(app.rect_r.Position)
                rect = app.rect_r.Position;
                rect(3:4)=rect(1:2)+rect(3:4); % [xmin,ymin,xmax,ymax]
                
                [~,ind1]=min(abs(app.THETA(:,1)-rect(1)));
                [~,ind2]=min(abs(app.THETA(:,1)-rect(3)));
                [~,ind3]=min(abs(app.R(1,:)-rect(2)));
                [~,ind4]=min(abs(app.R(1,:)-rect(4)));
                
                f2 = finterp(ind1:ind2, ind3:ind4);
                Iavg = mean(f2, 1);
                
                r = app.R(1, ~isnan(Iavg));
                iavg = Iavg(~isnan(Iavg));
                
                if length(iavg)>4
                
                    plot(app.UIAxes6, r, iavg, '.-');
                    axis(app.UIAxes6, 'tight');
                    
                    ift = fftshift(fft(iavg));
                    
                    [~,ind]=sort(abs(ift),'descend');
                    phase = angle(ift(ind(2)));
                    freq = abs(ind(3)-ind(2))/2*1/(r(2)-r(1))/length(r);
                    
                    app.phase_r(end+1) = phase;
                    
                    app.disp_r(end+1) = (mod(phase-app.phase_r(1)+pi, 2*pi) - pi) / (2*pi) * app.radialpitchumEditField.Value;
                    
                    % Plot
                    plot(app.UIAxes4, app.disp_r);
                end
            end
        end
        
        function updateVoltages(app)
            if isa(app.DAC, 'serial') && strcmp(app.DAC.Status,'open')
                flushinput(app.DAC);
            end
            app.V1Gauge.Value=app.dacReadVolt(0) * app.volt_amp_ratio;
            app.V2Gauge.Value=app.dacReadVolt(1) * app.volt_amp_ratio;
            app.V3Gauge.Value=app.dacReadVolt(2) * app.volt_amp_ratio;
            app.VzGauge.Value=app.dacReadVolt(3) * app.volt_amp_ratio;
        end
    
        function setSteppingEnable(app, en)
            app.ZeroStepsButton.Enable=en;
            app.Minus18Button.Enable=en;
            app.MinusHalfButton.Enable=en;
            app.MinusQuarterButton.Enable=en;
            app.Plus18Button.Enable=en;
            app.PlusHalfButton.Enable=en;
            app.PlusQuarterButton.Enable=en;
        end
        
        function setPositioningEnable(app, en)
            app.GOButton.Enable = en;
        end
        
        function dacSetVolt(app, chan, volt)
            if ~isa(app.DAC, 'serial') || ~strcmp(app.DAC.Status,'open')
                errordlg('DAC connection not established');
                return;
            end
            if volt>10 || volt<-10
                errordlg('Voltage out of range');
                return;
            end
            chan = int32(chan);
            if chan>3 || chan<0
                errordlg('Channel out of range');
            end
            dacvalue = int32(interp1([-10,10],[0,65535],volt));
            fprintf(app.DAC, sprintf('C%d;D%d;', chan, dacvalue));
            app.Vdac(chan+1) = volt;
        end

        function volt=dacReadVolt(app, chan)
            chan = int32(chan);
            if chan>3 || chan<0
                errordlg('Channel out of range');
            end
            volt = app.Vdac(chan+1);
        end

        function setVoltages(app, volt, phase)
            if ~isa(app.DAC, 'serial') || ~strcmp(app.DAC.Status,'open')
                errordlg('DAC connection not established');
                return;
            end
            app.dacSetVolt(0,volt/app.volt_amp_ratio*MEMStepper_DAC_Approach.vphase(phase/180*pi, 0));
            app.dacSetVolt(1,volt/app.volt_amp_ratio*MEMStepper_DAC_Approach.vphase(phase/180*pi, -2*pi/3));
            app.dacSetVolt(2,volt/app.volt_amp_ratio*MEMStepper_DAC_Approach.vphase(phase/180*pi, 2*pi/3));
            
            app.updateVoltages();
        end
        
        function setVz(app, vz)
            if ~isa(app.DAC, 'serial') || ~strcmp(app.DAC.Status,'open')
                errordlg('DAC connection not established');
                return;
            end
            app.dacSetVolt(3, vz/app.volt_amp_ratio);
            app.updateVoltages();
        end


        function setVprobe(app, vp)
            if ~isa(app.LockIn, 'visa') || ~strcmp(app.LockIn.Status,'open')
                errordlg('Lockin connection not established');
                return;
            end
            fprintf(app.LockIn, 'auxv 1, %f\n', vp);
        end

        function v=readVprobe(app)
            if ~isa(app.LockIn, 'visa') || ~strcmp(app.LockIn.Status,'open')
                errordlg('Lockin connection not established');
                return;
            end
            v = str2double(query(app.LockIn,'oaux? 0\n'));
        end


        function alignReference(app, snap, ref, mag, minscale)
            % Scale snapshot to be on same scale as reference
            snap = imresize(snap, mag);
%             ref = imcomplement(ref);
                        
            % Multiscale rotation search to save time
            scale = [12, 10, 8, 6, 5, 4];
            rotrange = [-180, 180]; % Initial rotation range
            rotpts = 13; % Search points within the range for each scale
            
            figure(77907);
            ax=subplot(1,2,2);
            imshowpair(imgradient(snap), imgradient(ref));
            
            sc_final=[];
            first=true;
            
            for sc = scale
                fprintf('Scale=/%d, resolution=%f deg, ', sc, diff(rotrange)/(rotpts-1));
                % Downsample
                snap_sc = imresize(snap, 1/sc);
                ref_sc = imresize(ref, 1/sc);
                
                if sc>1 && (min(size(ref_sc))<20 || min(size(snap_sc))<20)
                    % Scale too coarse, skip the scale
                    fprintf('skipped\n');
                    rotpts = rotpts * 2;
                    continue;
                end
                
                if (max(size(snap_sc))>500)
                    break;
                end
                
                if sc < minscale
                    fprintf('scale too small\n');
                    break;
                end
                
                % Take gradient and binarize, then map to -1/1
%                 snap_sc = snap_sc;
%                 ref_sc =  ref_sc;
                
%                 snap_sc = int8(imbinarize(uint8(imgradient(snap_sc))))*127;
%                 ref_sc = int8(imbinarize(uint8(imgradient(ref_sc))))*127;

%                   snap_sc = imbinarize(snap_sc);
%                   ref_sc = imbinarize(ref_sc);
%                 snap_sc = snap_sc - mean(snap_sc(:));
%                 ref_sc = ref_sc - mean(ref_sc(:));
%                 snap_sc = snap_sc - int8(mean(snap_sc(:)));
%                 ref_sc = ref_sc - int8(mean(ref_sc(:)));
                snap_sc = imgradient(snap_sc);
                ref_sc = imgradient(ref_sc);
                
%                 PSF = fspecial('gaussian',5,1); % create PSF
%                 snap_sc = imfilter(snap_sc,PSF,'replicate','same','conv');
%                 ref_sc = imfilter(ref_sc,PSF,'replicate','same','conv');
                
%                 snap_sc = imbinarize(uint8(snap_sc));
%                 ref_sc = imbinarize(uint8(ref_sc));
                
                bestrot=[];
                bestcorr=0;
                bestsnaprot=[];
                if first
                    % Double points for first range
                    range = linspace(rotrange(1), rotrange(2), 2*rotpts);
                else
                    range = linspace(rotrange(1), rotrange(2), rotpts);
                end
                
                first=false;
                for rot = range
                    rot
                    rot_mat = [cosd(rot) -sind(rot);sind(rot) cosd(rot)];
                    tform = rigid2d(rot_mat, [0 0]);
                    snap_sc_rot = imwarp(snap_sc, tform, 'FillValues', 0);
                    
%                     snap_sc_rot = imrotate(snap_sc, rot, "bilinear", "crop");
                    if app.UseGPUCheckBox.Value
                       corr = gather(xcorr2(gpuArray(single(ref_sc)), gpuArray(single(snap_sc_rot))));
                    else
                       corr = xcorr2(single(ref_sc), single(snap_sc_rot));
                    end
                    
                    ax=subplot(1,2,1);
                    surf(ax, corr, 'EdgeColor', 'none');
                    if max(corr(:)) > max(bestcorr(:))
                        bestcorr=corr;
                        bestrot=rot;
                        bestsnaprot = snap_sc_rot;
                    end
                    drawnow;
                end
                
                fprintf('best=%f deg\n', bestrot);
                
                % Extract translation vector
                [ssr,snd] = max(bestcorr(:));
                [ypeak,xpeak] = ind2sub(size(bestcorr),snd);
                offset = [(xpeak-size(bestsnaprot,2)) (ypeak-size(bestsnaprot,1))];
                
                
                % Create coordinate system
                Rsnap = imref2d(size(bestsnaprot),sc,sc);
                Rref = imref2d(size(ref_sc),sc,sc);
                
                [bestsnaprot_t, Rsnap_t]=imtranslate(bestsnaprot, Rsnap, offset*sc, 'FillValues', 0, 'OutputView', 'full');
                
                
                ax=subplot(1,2,2);
                imshowpair(bestsnaprot_t, Rsnap_t, ref_sc, Rref);
                
                % Refile rotation angle search range
                % Shrink range by 4
                range = 0.25*(rotrange(2)-rotrange(1));
                rotrange = [bestrot-range, bestrot+range];

                sc_final = sc;
                offset_final = offset*sc;
                
%                 [~,ind]=min(abs(range-bestrot));
%                 rotrange = [range(max(ind-2,1)), range(min(ind+2,end))];
            end
            fprintf('Done\n');
            
            if isempty(sc_final)
                % Nothing has been done!
                return;
            end
            
        
            % Now correct the full image
            
            
            % world offset
            % Note that imwarp rotates wrt top left corner
            % whereas imrotate rotates around center
            
            rot_w = [cosd(bestrot) -sind(bestrot);sind(bestrot) cosd(bestrot)];
            tform_w = rigid2d(rot_w, [0 0]);
            snap_aligned =imwarp(snap, tform_w, 'FillValues', 0);
            
            
%             offset_w = [size(snap,2)/2  size(snap,1)/2]*(eye(2)-rot_w) + offset_final;


            Rsnap = imref2d(size(snap_aligned),1,1);
            Rref = imref2d(size(ref),1,1);
            
            [snap_aligned, Rsnap] = imtranslate(snap_aligned, Rsnap, offset_final, 'FillValues', 0, 'OutputView', 'full');


%             snap_aligned = imrotate(snap, bestrot, "bilinear", "crop");
            
            ax=subplot(1,2,2);
            imshowpair(snap_aligned, Rsnap, ref, Rref);
        end
        
        function goTo(app, volt, target, microstep, speed)
            curr_step = app.StepsEditField_2.Value;
            total_steps = round(abs(target-curr_step)*(microstep));
            wait_time = 1/(speed * microstep);
            step_inc = 1/microstep*sign(target-curr_step);
            
            for i=1:total_steps
                curr_step = curr_step + step_inc;
                phase = curr_step * 120;
                app.setVoltages(volt, phase);
                pause(wait_time);
                if isa(app.video, 'videoinput') && isrunning(app.video)
                    trigger(app.video);
                end
                app.StepsEditField_2.Value = curr_step;
            end
            
        end

        function v = readLockin(app)
            if isa(app.LockIn, 'visa') && strcmp(app.LockIn.Status,'open')
                % Read lockin value if cap enabled
                if app.ReadcapCheckBox.Value
                    v = str2double(query(app.LockIn,'outp? 0\n'));
                    app.CapValue.Text = sprintf("%+9.3f uV", v*1e6);
                    app.capData(end+1) = v*1e6;
    %                 if length(app.capData) == 1
                    plot(app.AxesCapReadout, app.capData, '-');
    %                 else
    %                     app.AxesCapReadout.Children{1}.YData = app.capData;
    %                     drawnow;
    %                 end
                end

                if app.ReadprobeCheckBox.Value
                    v = str2double(query(app.LockIn,'oaux? 0\n'));
                    app.ProbeValue.Text = sprintf("%+6.3f V", v);
                end
            else
                v = 0;
            end
        end

        function timerCallback(app, src, evt)
            app.readLockin();
        end

        function addCapFittingPoint(app)
            if app.TabGroup3.SelectedTab == app.SteppingTab
                pause(app.WaitTimescaponlyEditField.Value);
                % Prevent data racing
                app.tim.stop();
                app.readLockin();
                if strcmp(app.StartStopCapButton.Text{1},"Stop")
                    app.tim.start();
                end
                if ~isempty(app.capData)
                    x = app.StepsEditField.Value;
                    y = app.capData(end);
                    app.capFitData(end+1,:) = [x,y];
                    plot(app.AxesCapFitting, app.capFitData(:,1), app.capFitData(:,2), '.', 'MarkerSize',5);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            image(app.UIAxes, zeros(1280,960));
            axis(app.UIAxes, 'tight');
            colormap(app.UIAxes,'gray');
            app.C=[];
            app.hImage=[];
            app.hImage2=[];
            app.snapshot=[];
            app.vw=[];
            app.scanUSBPort();
            app.scanCOMPort();
            app.LockIn=[];
            app.DAC=[];
            app.Vdac=zeros(4,1);
            app.capData=[];
            app.capFitData=[];
            app.setSteppingEnable(false);
            app.setPositioningEnable(false);
            app.updateAvailableDevices();
%             app.DeviceName.Value = app.DeviceName.ItemsData{end};
            app.updateAvailableResolution();

            app.volt_amp_ratio = 25;
            app.tim = timer('Period',0.25,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.tim.TimerFcn = @app.timerCallback;
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
            if strcmpi(app.StartPreviewButton.Text, "Start Preview")
                app.cameraConfig(app.ExposureSlider.Value, app.GainSlider.Value);
                app.cameraStartPreview();
                app.StartPreviewButton.Text = "Stop Preview";
                app.PickcircumferenceButton.Enable=true;
                app.SnapshotButton.Enable=true;
                app.ContinuousButton.Enable=true;
                app.RecordMovieButton.Enable=true;
                app.ChoosethetarangeButton.Enable=true;
                app.ChooseradialrangeButton.Enable=true;
            else
                app.cameraStopPreview();
                app.StartPreviewButton.Text = "Start Preview";
                app.PickcircumferenceButton.Enable=false;
                app.SnapshotButton.Enable=false;
                app.RecordMovieButton.Enable=false;
                app.ContinuousButton.Enable=false;
                app.ChoosethetarangeButton.Enable=false;
                app.ChooseradialrangeButton.Enable=false;
            end
        end

        % Button pushed function: PickcircumferenceButton
        function PickcircumferenceButtonPushed(app, event)
            if length(app.res)~=2
                errordlg('Start video first');
                return;   
            end
            
            for i = length(app.UIAxes.Children):-1:1
                if ~isa(app.UIAxes.Children(i), 'matlab.graphics.primitive.Image')
                    delete(app.UIAxes.Children(i));
                end
            end
            [x,y]=ginputuiax(app.UIAxes, 3);
            if length(x)~=3
                return;
            end
            
            x=round(x);
            y=round(y);
            
            % Get snapshot and find maximum in gradient
            F=getsnapshot(app.video);
            if size(F,3)~=1
                F=rgb2gray(F);
            end
            G = imgradient(double(F));
            w=10;
            for i=1:3
                Gcrop = G(max(1,y(i)-w):min(end,y(i)+w),max(1,x(i)-w):min(end,x(i)+w)); % Window centered at (x,y)
                [~,ind]=max(Gcrop(:));
                [yy,xx] = ind2sub(size(Gcrop), ind);
                y(i)=yy+max(1,y(i)-w)-1;
                x(i)=xx+max(1,x(i)-w)-1;
            end
            
            [r, app.C] = fit_circle_through_3_points([x(:),y(:)]);
            if any(isnan(app.C))
                return;
            end
            rectangle(app.UIAxes, 'Position', [app.C(1)-r, app.C(2)-r, 2*r, 2*r], 'Curvature', [1,1],'EdgeColor','r', 'LineWidth', 2);
            
            % Calculate necessary matrices to accelerate processing
            app.prepare();
            
            app.rect_r=[];
            app.rect_th=[];
        end

        % Value changing function: ExposureSlider
        function ExposureSliderValueChanging(app, event)
            value = event.Value;
            if isa(app.video, 'videoinput') && ~isrunning(app.video)
                src = getselectedsource(app.video);
                try
                    src.Exposure = value;
                catch
                end
            end
        end

        % Value changing function: GainSlider
        function GainSliderValueChanging(app, event)
            value = event.Value;
            if isa(app.video, 'videoinput') && ~isrunning(app.video)
                src = getselectedsource(app.video);
                try
                    src.Gain = value;
                catch
                end
            end
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            if ~isa(app.video, 'videoinput')
                errordlg('Start preview first');
                return;
            end
            
            app.snapshot = getsnapshot(app.video);
            
            if length(app.C)==2
                % Process one frame
                app.process(app.snapshot);
                return;
            else
                % Directly plot
                app.hImage2=image(app.UIAxes2, app.snapshot);
                axis(app.UIAxes2, 'tight');
            end
            
            if app.SyncSnapshotinworkspaceCheckBox.Value
                % Save snapshot to workspace
                assignin('base', app.VariableNameEditField.Value, app.snapshot);
            end
            
        end

        % Button pushed function: ContinuousButton
        function ContinuousButtonPushed(app, event)
            if ~isa(app.video, 'videoinput')
                errordlg('Start preview first');
                return;
            end
            
            if length(app.C)~=2
                errordlg('Pick circumference first');
                return;
            end
            
            if strcmpi(app.ContinuousButton.Text, 'Continuous')
                % Setup continuous acquisition+analysis
                app.RecordMovieButton.Enable = false;
                app.SnapshotButton.Enable = false;
                triggerconfig(app.video, 'immediate');
                
                % Set callback
                app.video.TriggerFcn = {@triggerfn, app};
                start(app.video);
                
                app.ContinuousButton.Text = {'Stop','Continuous'};
            else
                app.ContinuousButton.Text = 'Continuous';
                stop(app.video);
                app.video.TriggerFcn = {};
                app.RecordMovieButton.Enable = true;
                app.SnapshotButton.Enable = true;
            end
            
            function triggerfn(vid, event, app)
                F=getdata(vid,1);
                app.process(F);
                flushdata(vid,'all'); % Remove all frames stored in memory
                if ~isempty(app.hImage)
                    app.hImage.CData = F;
                end
                drawnow;
            end
        end

        % Button pushed function: ChoosethetarangeButton
        function ChoosethetarangeButtonPushed(app, event)
            if isempty(app.snapshot)
                errordlg('Take snapshot first');
                return;
            end
            for i = length(app.UIAxes2.Children):-1:1
                if isa(app.UIAxes2.Children(i), 'images.roi.Rectangle') && strcmp(app.UIAxes2.Children(i).Label, 'Theta')
                    delete(app.UIAxes2.Children(i));
                end
            end
%             [~,y]=ginputuiax(app.UIAxes2,2,'Y');
%             if length(y)~=2
%                 return;
%             end
%             line(app.UIAxes2,[app.THETA(1,1),app.THETA(end,1)],[1,1]*y(1),'Color','red');
%             line(app.UIAxes2,[app.THETA(1,1),app.THETA(end,1)],[1,1]*y(2),'Color','red');
%             app.rsel = sort(y);

            app.rect_th = drawrectangle(app.UIAxes2, 'Label','Theta', 'LineWidth', 1);
            addlistener(app.rect_th, 'DeletingROI', @deletecb);
            app.process(app.snapshot);
            
            % Called before deletion to make sure we don't use it again
            function deletecb(src,evt)
                a=ancestor(src, 'Figure');
                ap=a.RunningAppInstance;
                ap.rect_th=[];
            end
        end

        % Button pushed function: ChooseradialrangeButton
        function ChooseradialrangeButtonPushed(app, event)
            if isempty(app.snapshot)
                errordlg('Take snapshot first');
                return;
            end
            for i = length(app.UIAxes2.Children):-1:1
                if isa(app.UIAxes2.Children(i), 'images.roi.Rectangle') && strcmp(app.UIAxes2.Children(i).Label, 'Radial')
                    delete(app.UIAxes2.Children(i));
                end
            end
            app.rect_r = drawrectangle(app.UIAxes2, 'Label','Radial', 'LineWidth', 1);
            addlistener(app.rect_r, 'DeletingROI', @deletecb);
            app.process(app.snapshot);
            
            % Called before deletion to make sure we don't use it again
            function deletecb(src,evt)
                a=ancestor(src, 'Figure');
                ap=a.RunningAppInstance;
                ap.rect_r=[];
            end
        end

        % Button pushed function: ClearGraphsButton
        function ClearGraphsButtonPushed(app, event)
            app.disp_r=[];
            app.disp_th=[];
            app.phase_r=[];
            app.phase_th=[];
            cla(app.UIAxes5);
            cla(app.UIAxes4);
        end

        % Value changed function: AlignpeakCheckBox
        function AlignpeakCheckBoxValueChanged(app, event)
            value = app.AlignpeakCheckBox.Value;
            app.disp_th=[];
            app.phase_th=[];
            cla(app.UIAxes5);
        end

        % Drop down opening function: LockinDropDown
        function LockinDropDownOpening(app, event)
            app.scanUSBPort();
        end

        % Button pushed function: ConnectLockinButton
        function ConnectLockinButtonPushed(app, event)
            if isa(app.LockIn, 'visa')
                fclose(app.LockIn);
                delete(app.LockIn);
            end

            prev = instrfind('Type', 'visa-usb', 'RsrcName', app.LockinDropDown.Value, 'Status', 'open');
            if ~isempty(prev)
                for p=prev
                    fclose(p);
                end
            end
            try
                app.LockIn=visa('NI', app.LockinDropDown.Value, 'EOSMode', 'read&write', 'EOSCharCode', 'LF', 'Timeout', 0.5);
                fopen(app.LockIn);
                app.SetVdButton.Enable = true;
                app.SetVprobeButton.Enable = true;
                app.StartStopCapButton.Enable = true;
                app.VdField.Value = str2double(query(app.LockIn, "auxv? 0\n"));
                app.ProbeVoltageEditField.Value = str2double(query(app.LockIn, "auxv? 1\n"));
            catch e
                e
                errordlg(sprintf('Cannot connect to %s', app.LockinDropDown.Value));
            end
        end

        % Button pushed function: ConnectDACButton
        function ConnectDACButtonPushed(app, event)
            if isa(app.DAC, 'visa')
                fclose(app.DAC);
                delete(app.DAC);
            end

            prev = instrfind('Type', 'serial', 'Name', ['Serial-',app.DACDropDown.Value], 'Status', 'open');
            if ~isempty(prev)
                for p=prev
                    fclose(p);
                end
            end
            try
                app.DAC=serial(app.DACDropDown.Value, 'BaudRate', 115200, 'Timeout', 0.5);
                fopen(app.DAC);

                % Set all channels to zero volt and enable
                for i=1:4
                    fprintf(app.DAC, sprintf('C%d;D32767;N;', int32(i)));
                end
                app.Vdac=zeros(4,1);
                app.updateVoltages();

                app.V1Knob.Value = app.V1Gauge.Value;
                app.V2Knob.Value = app.V2Gauge.Value;
                app.V3Knob.Value = app.V3Gauge.Value;
                app.V1Label.Value = app.V1Gauge.Value;
                app.V2Label.Value = app.V2Gauge.Value;
                app.V3Label.Value = app.V3Gauge.Value;
                app.SetButton.Enable = true;
                app.InitButton.Enable = true;
                app.SetVzButton.Enable = true;
                app.ZeroVzButton.Enable = true;
                app.FlashVzButton.Enable = true;

                app.VzField.Value = app.VzGauge.Value;

            catch e
                errordlg(sprintf('Cannot connect to %s, error msg: %s', app.DACDropDown.Value, e.message));
            end
        end

        % Button pushed function: OUTPUTOFFButton
        function OUTPUTOFFButtonPushed(app, event)
            app.V1Knob.Value=0;
            app.V2Knob.Value=0;
            app.V3Knob.Value=0;
            app.VzField.Value=0;
            app.V1Label.Value = 0;
            app.V2Label.Value = 0;
            app.V3Label.Value = 0;
            app.setSteppingEnable(false); % Stepping needs to be reinitialized
            app.setPositioningEnable(false); % Positioning needs to be reinitialized
            if isa(app.DAC, 'serial') && strcmp(app.DAC.Status,'open')
                app.dacSetVolt(0,0);
                app.dacSetVolt(1,0);
                app.dacSetVolt(2,0);
                app.dacSetVolt(3,0);
                pause(0.1);
                app.updateVoltages();
            end
        end

        % Value changing function: V1Knob
        function V1KnobValueChanging(app, event)
            app.V1Label.Value = event.Value;
        end

        % Value changing function: V2Knob
        function V2KnobValueChanging(app, event)
            app.V2Label.Value = event.Value;
        end

        % Value changing function: V3Knob
        function V3KnobValueChanging(app, event)
            app.V3Label.Value = event.Value;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            if isa(app.DAC, 'serial') && strcmp(app.DAC.Status,'open')
                app.dacSetVolt(0,app.V1Knob.Value/app.volt_amp_ratio);
                app.dacSetVolt(1,app.V2Knob.Value/app.volt_amp_ratio);
                app.dacSetVolt(2,app.V3Knob.Value/app.volt_amp_ratio);
                app.updateVoltages();
            end
            
            app.setSteppingEnable(false); % Stepping needs to be reinitialized
            app.setPositioningEnable(false); % Positioning needs to be reinitialized
        end

        % Value changed function: V1Label
        function V1LabelValueChanged(app, event)
            app.V1Knob.Value = app.V1Label.Value;
        end

        % Value changed function: V2Label
        function V2LabelValueChanged(app, event)
            app.V2Knob.Value = app.V2Label.Value;
        end

        % Value changed function: V3Label
        function V3LabelValueChanged(app, event)
            app.V3Knob.Value = app.V3Label.Value;
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            app.PhaseEditField.Value = 0;
            app.StepsEditField.Value = 0;
            app.setVoltages(app.DriveVoltageEditField.Value, app.PhaseEditField.Value);
            app.setSteppingEnable(true);
            if app.SyncCapFittingCheckBox.Value
                app.addCapFittingPoint();
            end
        end

        % Button pushed function: Minus18Button
        function Minus18ButtonPushed(app, event)
            app.PhaseEditField.Value = mod(app.PhaseEditField.Value - 15, 360);
            app.StepsEditField.Value = app.StepsEditField.Value - 0.125;
            app.setVoltages(app.DriveVoltageEditField.Value, app.PhaseEditField.Value);
            if app.SyncCapFittingCheckBox.Value
                app.addCapFittingPoint();
            end
        end

        % Button pushed function: Plus18Button
        function Plus18ButtonPushed(app, event)
            app.PhaseEditField.Value = mod(app.PhaseEditField.Value + 15, 360);
            app.StepsEditField.Value = app.StepsEditField.Value + 0.125;
            app.setVoltages(app.DriveVoltageEditField.Value, app.PhaseEditField.Value);
            if app.SyncCapFittingCheckBox.Value
                app.addCapFittingPoint();
            end
        end

        % Button pushed function: MinusHalfButton
        function MinusHalfButtonPushed(app, event)
            app.PhaseEditField.Value = mod(app.PhaseEditField.Value - 60, 360);
            app.StepsEditField.Value = app.StepsEditField.Value - 0.5;
            app.setVoltages(app.DriveVoltageEditField.Value, app.PhaseEditField.Value);
            if app.SyncCapFittingCheckBox.Value
                app.addCapFittingPoint();
            end
        end

        % Button pushed function: PlusHalfButton
        function PlusHalfButtonPushed(app, event)
            app.PhaseEditField.Value = mod(app.PhaseEditField.Value + 60, 360);
            app.StepsEditField.Value = app.StepsEditField.Value + 0.5;
            app.setVoltages(app.DriveVoltageEditField.Value, app.PhaseEditField.Value);
            if app.SyncCapFittingCheckBox.Value
                app.addCapFittingPoint();
            end
        end

        % Button pushed function: MinusQuarterButton
        function MinusQuarterButtonPushed(app, event)
            app.PhaseEditField.Value = mod(app.PhaseEditField.Value - 30, 360);
            app.StepsEditField.Value = app.StepsEditField.Value - 0.25;
            app.setVoltages(app.DriveVoltageEditField.Value, app.PhaseEditField.Value);
            if app.SyncCapFittingCheckBox.Value
                app.addCapFittingPoint();
            end
        end

        % Button pushed function: PlusQuarterButton
        function PlusQuarterButtonPushed(app, event)
            app.PhaseEditField.Value = mod(app.PhaseEditField.Value + 30, 360);
            app.StepsEditField.Value = app.StepsEditField.Value + 0.25;
            app.setVoltages(app.DriveVoltageEditField.Value, app.PhaseEditField.Value);
            if app.SyncCapFittingCheckBox.Value
                app.addCapFittingPoint();
            end
        end

        % Button pushed function: ZeroStepsButton
        function ZeroStepsButtonPushed(app, event)
            app.StepsEditField.Value = 0;
        end

        % Drop down opening function: Resolution
        function ResolutionDropDownOpening(app, event)
            app.updateAvailableResolution();
        end

        % Value changed function: DeviceName
        function DeviceNameValueChanged(app, event)
            app.updateAvailableResolution();
            cla(app.UIAxes);
            app.C=[];
            app.hImage=[];
        end

        % Value changed function: Resolution
        function ResolutionValueChanged(app, event)
            cla(app.UIAxes);
            app.C=[];
            app.hImage=[];
        end

        % Drop down opening function: DeviceName
        function DeviceNameDropDownOpening(app, event)
            app.updateAvailableDevices();
        end

        % Button pushed function: SavesnapshottofileButton
        function SavesnapshottofileButtonPushed(app, event)
            if isnumeric(app.snapshot) && ~isempty(app.snapshot)
                [file, path] = uiputfile('*.png', 'Save snapshot as', 'Fsnap.png');
                if file ~= 0
                    imwrite(app.snapshot, fullfile(path, file));
                end
            end
        end

        % Button pushed function: PickButton
        function PickButtonPushed(app, event)
            [file,path] = uigetfile('*.png', 'Pick reference image');
            app.ReferenceDesignEditField.Value = [path file];
        end

        % Button pushed function: PreviewRefDesignButton
        function PreviewRefDesignButtonPushed(app, event)
            if isfile(app.ReferenceDesignEditField.Value)
                G = imread(app.ReferenceDesignEditField.Value);
                G = imbinarize(G);
                G = imcomplement(G);
                h=figure;
                imshow(G);
            end
        end

        % Button pushed function: 
        % AlignsnapshottoRefDesignExperimentalButton
        function AlignsnapshottoRefDesignExperimentalButtonPushed(app, event)
            if isfile(app.ReferenceDesignEditField.Value) && isnumeric(app.snapshot) && ~isempty(app.snapshot)
                G = imread(app.ReferenceDesignEditField.Value);
                app.alignReference(app.snapshot, G, str2double(app.MicroscopeObjectiveDropDown.Value), app.MinScaleEditField.Value);
            end
        end

        % Button pushed function: InitResetButton
        function InitResetButtonPushed(app, event)
            app.StepsEditField_2.Value = 0;
            app.TargetStepEditField.Value = 0;
            app.setVoltages(0, 0); % Turn off voltage to reset the motor
            pause(0.5);
            app.setVoltages(app.DriveVoltageEditField_2.Value, 0);
            app.setPositioningEnable(true); 
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            microstep = str2double(app.MicrostepDropDown.Value);
            target = round(app.TargetStepEditField.Value*microstep)/microstep; % Round to nearest microstep
            
            app.goTo(app.DriveVoltageEditField_2.Value, target, microstep, app.SpeedstepsEditField.Value);
        end

        % Button pushed function: RecordMovieButton
        function RecordMovieButtonPushed(app, event)

             if strcmpi(app.RecordMovieButton.Text, "Record Movie")
                
                % Save video
                [file, path] = uiputfile({'*.mp4';'*.avi';'*.*'}, 'Save movie as', sprintf('movie-%s.mp4', datestr(datetime('now'), "yymmdd-HHMMSS")));
                if file==0
                    return;
                end
                filename = fullfile(path, file);
                [~,~,ext] = fileparts(filename);
                if strcmpi(ext, '.avi')
                    app.vw = VideoWriter(filename, "Uncompressed AVI");
                else
                    app.vw = VideoWriter(filename, "MPEG-4");
                    app.vw.Quality = 85;
                end
                app.vw.FrameRate = 30;
                open(app.vw);
                
                triggerconfig(app.video, 'manual');
                % Set callback
                app.video.TriggerFcn = {@triggerfn, app};
                start(app.video);
                    
                app.RecordMovieButton.Text = "Stop Record";
                app.SnapshotButton.Enable=false;
                app.ContinuousButton.Enable=false;
             else
                
                stop(app.video);
                app.video.TriggerFcn = {};
                
                close(app.vw);
                
                app.RecordMovieButton.Text = "Record Movie";
                app.SnapshotButton.Enable=true;
                app.ContinuousButton.Enable=true;
             end
             
             
            function triggerfn(vid, event, app)
                F=getdata(vid,1);
                writeVideo(app.vw, F);
                flushdata(vid,'all'); % Remove all frames stored in memory
                if ~isempty(app.hImage)
                    app.hImage.CData = F;
                    drawnow;
                end
            end
        end

        % Button pushed function: SetVdButton
        function SetVdButtonPushed(app, event)
            if isa(app.LockIn, 'visa') && strcmp(app.LockIn.Status,'open')
                fprintf(app.LockIn, "auxv 0,%f\n", app.VdField.Value);
            end
        end

        % Button pushed function: SetVprobeButton
        function SetVprobeButtonPushed(app, event)
            if isa(app.LockIn, 'visa') && strcmp(app.LockIn.Status,'open')
                app.setVprobe(app.ProbeVoltageEditField.Value);
            end
        end

        % Button pushed function: StartStopCapButton
        function StartStopCapButtonPushed(app, event)
            if strcmp(app.StartStopCapButton.Text{1}, "Start")
                % Start update
                app.StartStopCapButton.Text{1} = 'Stop';
                app.tim.start();
            else
                app.StartStopCapButton.Text{1} = 'Start';
                app.tim.stop();
            end
        end

        % Button pushed function: ClearCapButton
        function ClearCapButtonPushed(app, event)
            app.capData = [];
            app.capFitData = [];
            cla(app.AxesCapReadout);
            cla(app.AxesCapFitting);
        end

        % Button pushed function: SetVzButton
        function SetVzButtonPushed(app, event)
            if isa(app.DAC, 'serial') && strcmp(app.DAC.Status,'open')
                app.setVz(app.VzField.Value);
            end
        end

        % Button pushed function: ZeroVzButton
        function ZeroVzButtonPushed(app, event)
            if isa(app.DAC, 'serial') && strcmp(app.DAC.Status,'open')
                app.setVz(0);
            end
        end

        % Button pushed function: FlashVzButton
        function FlashVzButtonPushed(app, event)
            if isa(app.DAC, 'serial') && strcmp(app.DAC.Status,'open')
                for i=1:20
                    app.setVz(app.VzField.Value);
                    pause(0.1);
                    app.setVz(0);
                    pause(0.1);
                end
                app.setVz(0);
            end
        end

        % Button pushed function: ApproachZButton
        function ApproachZButtonPushed(app, event)
            if ~(isa(app.DAC, 'serial') && strcmp(app.DAC.Status, 'open'))
                errordlg('DAC not connected');
                return;
            end
            if ~(isa(app.LockIn, 'visa') && strcmp(app.LockIn.Status, 'open'))
                errordlg('LockIn not connected');
                return;
            end

            % Stop the update timer if it is running
            timer_running = false;
            if app.tim.Running
                app.tim.stop();
                timer_running = true;
            end
            app.StartStopCapButton.Enable=false;

            % Create new figure for tip approach
            h=figure(1100);clf(h);
            ax = axes(h);
            title(ax, 'Tip approach');
            xlabel(ax, 'Vz (V)');
            ylabel(ax, 'Vprobe (V)');
            hold(ax, 'on');

            vzmax = app.ApproachMaxVEditField.Value;
            vzstep = app.Vstepapproach.Value;
            vzlist = 0:vzstep:vzmax;
            vpread = zeros(size(vzlist))*nan;
            app.setVz(0);
            pause(0.1);
            vpinitial = app.readVprobe();
            fplot(ax,@(x)vpinitial, [0, vzmax], ':k');
            c = plot(ax,vzlist, vpread, 'o-b', 'MarkerSize', 2);
            ylim(ax, [0,vpinitial*1.1]);
            for i = 1:length(vzlist)
                app.setVz(vzlist(i));
                pause(0.1);
                vpread(i) = app.readVprobe();
                c.YData = vpread;
                drawnow;
                if vpread(i) < vpinitial * 0.9
                    break;
                end
            end


            % Resume timer if it was running
            if timer_running
                app.tim.start();
            end
            app.StartStopCapButton.Enable=true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MEMSStepperControlUIFigure = uifigure('Visible', 'off');
            app.MEMSStepperControlUIFigure.Name = 'MEMS Stepper Control';
            app.GridLayout = uigridlayout(app.MEMSStepperControlUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Camera View')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.CameraControlTab = uitab(app.TabGroup2);
            app.CameraControlTab.Title = 'Camera Control';
            app.GridLayout3 = uigridlayout(app.CameraControlTab);
            app.ExposureSlider = uislider(app.GridLayout3);
            app.ExposureSlider.Limits = [-10 10];
            app.ExposureSlider.MajorTicks = [-10 -5 0 5 10];
            app.ExposureSlider.ValueChangingFcn = createCallbackFcn(app, @ExposureSliderValueChanging, true);
            app.ExposureSlider.Value = -5;
            app.ExposureSliderLabel = uilabel(app.GridLayout3);
            app.ExposureSliderLabel.Text = 'Exposure';
            app.GainSliderLabel = uilabel(app.GridLayout3);
            app.GainSliderLabel.Text = 'Gain';
            app.GainSlider = uislider(app.GridLayout3);
            app.GainSlider.Limits = [0 300];
            app.GainSlider.ValueChangingFcn = createCallbackFcn(app, @GainSliderValueChanging, true);
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.DeviceName = uidropdown(app.GridLayout7);
            app.DeviceName.Items = {'1', '2', '3', '4', '5', ''};
            app.DeviceName.DropDownOpeningFcn = createCallbackFcn(app, @DeviceNameDropDownOpening, true);
            app.DeviceName.ValueChangedFcn = createCallbackFcn(app, @DeviceNameValueChanged, true);
            app.DeviceName.Value = '3';
            app.DeviceLabel = uilabel(app.GridLayout7);
            app.DeviceLabel.Text = 'Device #';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.Resolution = uidropdown(app.GridLayout8);
            app.Resolution.DropDownOpeningFcn = createCallbackFcn(app, @ResolutionDropDownOpening, true);
            app.Resolution.ValueChangedFcn = createCallbackFcn(app, @ResolutionValueChanged, true);
            app.ResolutionLabel = uilabel(app.GridLayout8);
            app.ResolutionLabel.Text = 'Resolution';
            app.MicroscopeObjectiveDropDownLabel = uilabel(app.GridLayout3);
            app.MicroscopeObjectiveDropDownLabel.Text = {'Microscope'; 'Objective'};
            app.MicroscopeObjectiveDropDown = uidropdown(app.GridLayout3);
            app.MicroscopeObjectiveDropDown.Items = {'5x', '10x', '40x', '100x'};
            app.MicroscopeObjectiveDropDown.ItemsData = {'0.6047', '0.2402', '0.07269', '0.02939', '', ''};
            app.MicroscopeObjectiveDropDown.Value = '0.2402';
            app.MotionDetectionTab = uitab(app.TabGroup2);
            app.MotionDetectionTab.Title = 'Motion Detection';
            app.GridLayout4 = uigridlayout(app.MotionDetectionTab);
            app.PickcircumferenceButton = uibutton(app.GridLayout4, 'push');
            app.PickcircumferenceButton.ButtonPushedFcn = createCallbackFcn(app, @PickcircumferenceButtonPushed, true);
            app.PickcircumferenceButton.Enable = 'off';
            app.PickcircumferenceButton.Text = {'Pick '; 'circumference'};
            app.ChoosethetarangeButton = uibutton(app.GridLayout4, 'push');
            app.ChoosethetarangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosethetarangeButtonPushed, true);
            app.ChoosethetarangeButton.Enable = 'off';
            app.ChoosethetarangeButton.Text = {'Choose'; 'theta range'};
            app.ChooseradialrangeButton = uibutton(app.GridLayout4, 'push');
            app.ChooseradialrangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseradialrangeButtonPushed, true);
            app.ChooseradialrangeButton.Enable = 'off';
            app.ChooseradialrangeButton.Text = {'Choose'; 'radial range'};
            app.AlignpeakCheckBox = uicheckbox(app.GridLayout4);
            app.AlignpeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlignpeakCheckBoxValueChanged, true);
            app.AlignpeakCheckBox.Text = {'Align'; 'peak'};
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.radialpitchumEditFieldLabel = uilabel(app.GridLayout10);
            app.radialpitchumEditFieldLabel.Text = {'radial pitch'; '(um)'};
            app.radialpitchumEditField = uieditfield(app.GridLayout10, 'numeric');
            app.radialpitchumEditField.Value = 8;
            app.MiscTab = uitab(app.TabGroup2);
            app.MiscTab.Title = 'Misc.';
            app.GridLayout9 = uigridlayout(app.MiscTab);
            app.SyncSnapshotinworkspaceCheckBox = uicheckbox(app.GridLayout9);
            app.SyncSnapshotinworkspaceCheckBox.Text = {'Sync Snapshot in '; 'workspace'};
            app.SyncSnapshotinworkspaceCheckBox.Value = true;
            app.SavesnapshottofileButton = uibutton(app.GridLayout9, 'push');
            app.SavesnapshottofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavesnapshottofileButtonPushed, true);
            app.SavesnapshottofileButton.Text = {'Save'; 'snapshot'; 'to file'};
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.PickButton = uibutton(app.GridLayout11, 'push');
            app.PickButton.ButtonPushedFcn = createCallbackFcn(app, @PickButtonPushed, true);
            app.PickButton.Text = 'Pick';
            app.ReferenceDesignEditField = uieditfield(app.GridLayout11, 'text');
            app.ReferenceDesignEditFieldLabel = uilabel(app.GridLayout11);
            app.ReferenceDesignEditFieldLabel.Text = {'Reference'; 'Design'};
            app.AlignsnapshottoRefDesignExperimentalButton = uibutton(app.GridLayout9, 'push');
            app.AlignsnapshottoRefDesignExperimentalButton.ButtonPushedFcn = createCallbackFcn(app, @AlignsnapshottoRefDesignExperimentalButtonPushed, true);
            app.AlignsnapshottoRefDesignExperimentalButton.Text = {'Align snapshot'; 'to Ref. Design'; '(Experimental)'};
            app.PreviewRefDesignButton = uibutton(app.GridLayout9, 'push');
            app.PreviewRefDesignButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewRefDesignButtonPushed, true);
            app.PreviewRefDesignButton.Text = {'Preview'; 'Ref. Design'};
            app.GridLayout12 = uigridlayout(app.GridLayout9);
            app.VarNameLabel = uilabel(app.GridLayout12);
            app.VarNameLabel.Text = {'Var'; 'Name'};
            app.VariableNameEditField = uieditfield(app.GridLayout12, 'text');
            app.VariableNameEditField.Value = 'Fsnap';
            app.UseGPUCheckBox = uicheckbox(app.GridLayout9);
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.GridLayout13 = uigridlayout(app.GridLayout9);
            app.MinScaleEditFieldLabel = uilabel(app.GridLayout13);
            app.MinScaleEditFieldLabel.Text = {'Min'; 'Scale'};
            app.MinScaleEditField = uieditfield(app.GridLayout13, 'numeric');
            app.MinScaleEditField.Limits = [1 16];
            app.MinScaleEditField.RoundFractionalValues = 'on';
            app.MinScaleEditField.Value = 2;
            app.ImageMotionDetectionPanel = uipanel(app.GridLayout);
            app.ImageMotionDetectionPanel.Title = 'Image Motion Detection';
            app.GridLayout2 = uigridlayout(app.ImageMotionDetectionPanel);
            app.UIAxes5 = uiaxes(app.GridLayout2);
            title(app.UIAxes5, '\theta Displacement')
            xlabel(app.UIAxes5, 'Frame')
            ylabel(app.UIAxes5, '\Delta\theta (^\circ)')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Radial Displacement')
            xlabel(app.UIAxes4, 'Frame')
            ylabel(app.UIAxes4, '\DeltaR (um)')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, '\theta Detection')
            xlabel(app.UIAxes3, '\theta')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YTick = [];
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'De-polar view')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes6 = uiaxes(app.GridLayout2);
            title(app.UIAxes6, 'Radial Detection')
            xlabel(app.UIAxes6, '\theta')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.YTick = [];
            app.ClearGraphsButton = uibutton(app.GridLayout2, 'push');
            app.ClearGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphsButtonPushed, true);
            app.ClearGraphsButton.Text = {'Clear'; 'Graphs'};
            app.MEMScontrolPanel = uipanel(app.GridLayout);
            app.MEMScontrolPanel.Title = 'MEMS control';
            app.GridLayout15 = uigridlayout(app.MEMScontrolPanel);
            app.TabGroup3 = uitabgroup(app.GridLayout15);
            app.DirectTab = uitab(app.TabGroup3);
            app.DirectTab.Title = 'Direct';
            app.SetButton = uibutton(app.DirectTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.V1Knob = uiknob(app.DirectTab, 'continuous');
            app.V1Knob.Limits = [-250 250];
            app.V1Knob.ValueChangingFcn = createCallbackFcn(app, @V1KnobValueChanging, true);
            app.V1KnobLabel = uilabel(app.DirectTab);
            app.V1KnobLabel.Text = 'V1';
            app.V2Knob = uiknob(app.DirectTab, 'continuous');
            app.V2Knob.Limits = [-250 250];
            app.V2Knob.ValueChangingFcn = createCallbackFcn(app, @V2KnobValueChanging, true);
            app.V2KnobLabel = uilabel(app.DirectTab);
            app.V2KnobLabel.Text = 'V2';
            app.V3Knob = uiknob(app.DirectTab, 'continuous');
            app.V3Knob.Limits = [-250 250];
            app.V3Knob.ValueChangingFcn = createCallbackFcn(app, @V3KnobValueChanging, true);
            app.V3KnobLabel = uilabel(app.DirectTab);
            app.V3KnobLabel.Text = 'V3';
            app.V3Label = uieditfield(app.DirectTab, 'numeric');
            app.V3Label.ValueChangedFcn = createCallbackFcn(app, @V3LabelValueChanged, true);
            app.V2Label = uieditfield(app.DirectTab, 'numeric');
            app.V2Label.ValueChangedFcn = createCallbackFcn(app, @V2LabelValueChanged, true);
            app.V1Label = uieditfield(app.DirectTab, 'numeric');
            app.V1Label.ValueChangedFcn = createCallbackFcn(app, @V1LabelValueChanged, true);
            app.SteppingTab = uitab(app.TabGroup3);
            app.SteppingTab.Title = 'Stepping';
            app.InitButton = uibutton(app.SteppingTab, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Enable = 'off';
            app.InitButton.Text = 'Init';
            app.Plus18Button = uibutton(app.SteppingTab, 'push');
            app.Plus18Button.ButtonPushedFcn = createCallbackFcn(app, @Plus18ButtonPushed, true);
            app.Plus18Button.Enable = 'off';
            app.Plus18Button.Text = {'+0.125'; ''};
            app.DriveVoltageEditField = uieditfield(app.SteppingTab, 'numeric');
            app.DriveVoltageEditField.Value = 20;
            app.DriveVoltageLabel = uilabel(app.SteppingTab);
            app.DriveVoltageLabel.Text = {'Drive'; 'Voltage'};
            app.PhaseEditFieldLabel = uilabel(app.SteppingTab);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.SteppingTab, 'numeric');
            app.PhaseEditField.Editable = 'off';
            app.StepsEditFieldLabel = uilabel(app.SteppingTab);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.SteppingTab, 'numeric');
            app.StepsEditField.Editable = 'off';
            app.ZeroStepsButton = uibutton(app.SteppingTab, 'push');
            app.ZeroStepsButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroStepsButtonPushed, true);
            app.ZeroStepsButton.Enable = 'off';
            app.ZeroStepsButton.Text = {'Zero'; 'Steps'};
            app.Minus18Button = uibutton(app.SteppingTab, 'push');
            app.Minus18Button.ButtonPushedFcn = createCallbackFcn(app, @Minus18ButtonPushed, true);
            app.Minus18Button.Enable = 'off';
            app.Minus18Button.Text = {'-0.125'; ''};
            app.PlusHalfButton = uibutton(app.SteppingTab, 'push');
            app.PlusHalfButton.ButtonPushedFcn = createCallbackFcn(app, @PlusHalfButtonPushed, true);
            app.PlusHalfButton.Enable = 'off';
            app.PlusHalfButton.Text = '+0.5';
            app.MinusHalfButton = uibutton(app.SteppingTab, 'push');
            app.MinusHalfButton.ButtonPushedFcn = createCallbackFcn(app, @MinusHalfButtonPushed, true);
            app.MinusHalfButton.Enable = 'off';
            app.MinusHalfButton.Text = '-0.5';
            app.PlusQuarterButton = uibutton(app.SteppingTab, 'push');
            app.PlusQuarterButton.ButtonPushedFcn = createCallbackFcn(app, @PlusQuarterButtonPushed, true);
            app.PlusQuarterButton.Enable = 'off';
            app.PlusQuarterButton.Text = '+0.25';
            app.MinusQuarterButton = uibutton(app.SteppingTab, 'push');
            app.MinusQuarterButton.ButtonPushedFcn = createCallbackFcn(app, @MinusQuarterButtonPushed, true);
            app.MinusQuarterButton.Enable = 'off';
            app.MinusQuarterButton.Text = '-0.25';
            app.SyncCapFittingCheckBox = uicheckbox(app.SteppingTab);
            app.SyncCapFittingCheckBox.Text = 'Sync Cap Fitting';
            app.SyncCapFittingCheckBox.Value = true;
            app.PositioningTab = uitab(app.TabGroup3);
            app.PositioningTab.Title = 'Positioning';
            app.DriveVoltageEditField_2 = uieditfield(app.PositioningTab, 'numeric');
            app.DriveVoltageEditField_2.Value = 20;
            app.DriveVoltageEditField_2Label = uilabel(app.PositioningTab);
            app.DriveVoltageEditField_2Label.Text = {'Drive'; 'Voltage'};
            app.InitResetButton = uibutton(app.PositioningTab, 'push');
            app.InitResetButton.ButtonPushedFcn = createCallbackFcn(app, @InitResetButtonPushed, true);
            app.InitResetButton.Text = 'Init/Reset';
            app.TargetStepEditField = uieditfield(app.PositioningTab, 'numeric');
            app.TargetStepEditFieldLabel = uilabel(app.PositioningTab);
            app.TargetStepEditFieldLabel.Text = {'Target'; 'Step'};
            app.StepsEditField_2Label = uilabel(app.PositioningTab);
            app.StepsEditField_2Label.Text = 'Steps';
            app.StepsEditField_2 = uieditfield(app.PositioningTab, 'numeric');
            app.StepsEditField_2.Editable = 'off';
            app.SpeedstepsEditField = uieditfield(app.PositioningTab, 'numeric');
            app.SpeedstepsEditField.Value = 2;
            app.SpeedstepsEditFieldLabel = uilabel(app.PositioningTab);
            app.SpeedstepsEditFieldLabel.Text = {'Speed'; 'step/s'};
            app.GOButton = uibutton(app.PositioningTab, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Enable = 'off';
            app.GOButton.Text = 'GO';
            app.MicrostepDropDownLabel = uilabel(app.PositioningTab);
            app.MicrostepDropDownLabel.Text = 'Microstep';
            app.MicrostepDropDown = uidropdown(app.PositioningTab);
            app.MicrostepDropDown.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.MicrostepDropDown.Value = '16';
            app.OUTPUTOFFButton = uibutton(app.GridLayout15, 'push');
            app.OUTPUTOFFButton.ButtonPushedFcn = createCallbackFcn(app, @OUTPUTOFFButtonPushed, true);
            app.OUTPUTOFFButton.Text = {'OUTPUT'; 'OFF'};
            app.SetVzButton = uibutton(app.GridLayout15, 'push');
            app.SetVzButton.ButtonPushedFcn = createCallbackFcn(app, @SetVzButtonPushed, true);
            app.SetVzButton.Enable = 'off';
            app.SetVzButton.Text = 'Set Vz';
            app.FlashVzButton = uibutton(app.GridLayout15, 'push');
            app.FlashVzButton.ButtonPushedFcn = createCallbackFcn(app, @FlashVzButtonPushed, true);
            app.FlashVzButton.Enable = 'off';
            app.FlashVzButton.Text = 'Flash Vz';
            app.DACDropDown = uidropdown(app.GridLayout15);
            app.DACDropDown.Items = {'a', 'b'};
            app.DACDropDown.Value = 'a';
            app.DACLabel = uilabel(app.GridLayout15);
            app.DACLabel.Text = 'DAC';
            app.ZeroVzButton = uibutton(app.GridLayout15, 'push');
            app.ZeroVzButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroVzButtonPushed, true);
            app.ZeroVzButton.Enable = 'off';
            app.ZeroVzButton.Text = 'Zero Vz';
            app.VzField = uieditfield(app.GridLayout15, 'numeric');
            app.VzField.Limits = [-250 250];
            app.VzField.ValueDisplayFormat = '%4.2f';
            app.ConnectDACButton = uibutton(app.GridLayout15, 'push');
            app.ConnectDACButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDACButtonPushed, true);
            app.ConnectDACButton.Text = 'Connect';
            app.StatusPanel = uipanel(app.GridLayout);
            app.StatusPanel.Title = 'Status';
            app.GridLayout16 = uigridlayout(app.StatusPanel);
            app.V2GaugeLabel = uilabel(app.GridLayout16);
            app.V2GaugeLabel.Text = 'V2';
            app.V2Gauge = uigauge(app.GridLayout16, 'semicircular');
            app.V2Gauge.Limits = [-250 250];
            app.V2Gauge.ScaleDirection = 'counterclockwise';
            app.V3GaugeLabel = uilabel(app.GridLayout16);
            app.V3GaugeLabel.Text = 'V3';
            app.V3Gauge = uigauge(app.GridLayout16, 'semicircular');
            app.V3Gauge.Limits = [-250 250];
            app.V3Gauge.ScaleDirection = 'counterclockwise';
            app.V1GaugeLabel = uilabel(app.GridLayout16);
            app.V1GaugeLabel.Text = 'V1';
            app.V1Gauge = uigauge(app.GridLayout16, 'semicircular');
            app.V1Gauge.Limits = [-250 250];
            app.V1Gauge.ScaleDirection = 'counterclockwise';
            app.VzGaugeLabel = uilabel(app.GridLayout16);
            app.VzGaugeLabel.Text = 'Vz';
            app.VzGauge = uigauge(app.GridLayout16, 'semicircular');
            app.VzGauge.Limits = [-250 250];
            app.VzGauge.ScaleDirection = 'counterclockwise';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.SnapshotButton = uibutton(app.GridLayout6, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Enable = 'off';
            app.SnapshotButton.Text = 'Snapshot';
            app.ContinuousButton = uibutton(app.GridLayout6, 'push');
            app.ContinuousButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuousButtonPushed, true);
            app.ContinuousButton.Enable = 'off';
            app.ContinuousButton.Text = 'Continuous';
            app.StartPreviewButton = uibutton(app.GridLayout6, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Text = 'Start Preview';
            app.RecordMovieButton = uibutton(app.GridLayout6, 'push');
            app.RecordMovieButton.ButtonPushedFcn = createCallbackFcn(app, @RecordMovieButtonPushed, true);
            app.RecordMovieButton.Enable = 'off';
            app.RecordMovieButton.Text = 'Record Movie';
            app.CapacitiveMotionDetectionPanel = uipanel(app.GridLayout);
            app.CapacitiveMotionDetectionPanel.Title = 'Capacitive Motion Detection';
            app.GridLayout17 = uigridlayout(app.CapacitiveMotionDetectionPanel);
            app.AxesCapReadout = uiaxes(app.GridLayout17);
            title(app.AxesCapReadout, {'Capacitance Readout'; ''})
            xlabel(app.AxesCapReadout, 'Time')
            ylabel(app.AxesCapReadout, 'Signal')
            zlabel(app.AxesCapReadout, 'Z')
            app.AxesCapFitting = uiaxes(app.GridLayout17);
            title(app.AxesCapFitting, 'Fitting')
            xlabel(app.AxesCapFitting, 'Steps')
            ylabel(app.AxesCapFitting, 'Signal')
            zlabel(app.AxesCapFitting, 'Z')
            app.ClearCapButton = uibutton(app.GridLayout17, 'push');
            app.ClearCapButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCapButtonPushed, true);
            app.ClearCapButton.Text = 'Clear';
            app.StartStopCapButton = uibutton(app.GridLayout17, 'push');
            app.StartStopCapButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopCapButtonPushed, true);
            app.StartStopCapButton.Enable = 'off';
            app.StartStopCapButton.Text = {'Start'; 'Update'};
            app.CapValue = uilabel(app.GridLayout17);
            app.CapValue.Text = '0.0 uV';
            app.ReadoutLabel = uilabel(app.GridLayout17);
            app.ReadoutLabel.Text = 'Readout';
            app.ProbeValue = uilabel(app.GridLayout17);
            app.ProbeValue.Text = '0.0 V';
            app.ProbeLabel = uilabel(app.GridLayout17);
            app.ProbeLabel.Text = 'Probe';
            app.MEMSReadoutPanel = uipanel(app.GridLayout);
            app.MEMSReadoutPanel.Title = 'MEMS Readout';
            app.GridLayout18 = uigridlayout(app.MEMSReadoutPanel);
            app.SR860Label = uilabel(app.GridLayout18);
            app.SR860Label.Text = 'SR860';
            app.LockinDropDown = uidropdown(app.GridLayout18);
            app.LockinDropDown.Items = {'a', 'b'};
            app.LockinDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LockinDropDownOpening, true);
            app.LockinDropDown.Value = 'a';
            app.ConnectLockinButton = uibutton(app.GridLayout18, 'push');
            app.ConnectLockinButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectLockinButtonPushed, true);
            app.ConnectLockinButton.Text = 'Connect';
            app.VdField = uieditfield(app.GridLayout18, 'numeric');
            app.VdField.Limits = [0 4];
            app.VdField.ValueDisplayFormat = '%4.2f';
            app.VdField.Value = 3;
            app.SetVdButton = uibutton(app.GridLayout18, 'push');
            app.SetVdButton.ButtonPushedFcn = createCallbackFcn(app, @SetVdButtonPushed, true);
            app.SetVdButton.Enable = 'off';
            app.SetVdButton.Text = 'Set Vd';
            app.WaitTimescaponlyEditFieldLabel = uilabel(app.GridLayout18);
            app.WaitTimescaponlyEditFieldLabel.Text = {'Wait Time (s)'; '(cap only)'};
            app.WaitTimescaponlyEditField = uieditfield(app.GridLayout18, 'numeric');
            app.WaitTimescaponlyEditField.Limits = [0 5];
            app.WaitTimescaponlyEditField.ValueDisplayFormat = '%5.2g';
            app.WaitTimescaponlyEditField.Value = 0.5;
            app.ProbeVoltageEditField = uieditfield(app.GridLayout18, 'numeric');
            app.SetVprobeButton = uibutton(app.GridLayout18, 'push');
            app.SetVprobeButton.ButtonPushedFcn = createCallbackFcn(app, @SetVprobeButtonPushed, true);
            app.SetVprobeButton.Enable = 'off';
            app.SetVprobeButton.Text = 'Set Vprobe';
            app.ReadcapCheckBox = uicheckbox(app.GridLayout18);
            app.ReadcapCheckBox.Text = {'Read cap'; ''};
            app.ReadprobeCheckBox = uicheckbox(app.GridLayout18);
            app.ReadprobeCheckBox.Text = 'Read probe';
            app.ApproachZButton = uibutton(app.GridLayout18, 'push');
            app.ApproachZButton.ButtonPushedFcn = createCallbackFcn(app, @ApproachZButtonPushed, true);
            app.ApproachZButton.Text = 'Approach Z';
            app.AppoachStepVEditFieldLabel = uilabel(app.GridLayout18);
            app.AppoachStepVEditFieldLabel.Text = 'Appoach Step (V)';
            app.Vstepapproach = uieditfield(app.GridLayout18, 'numeric');
            app.Vstepapproach.Limits = [0 100];
            app.Vstepapproach.Value = 0.1;
            app.ApproachMaxVEditFieldLabel = uilabel(app.GridLayout18);
            app.ApproachMaxVEditFieldLabel.Text = 'Approach Max (V)';
            app.ApproachMaxVEditField = uieditfield(app.GridLayout18, 'numeric');
            app.ApproachMaxVEditField.Limits = [0 200];
            app.ApproachMaxVEditField.Value = 50;
            % Show the figure after all components are created
            app.MEMSStepperControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MEMStepper_DAC_Approach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MEMSStepperControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MEMSStepperControlUIFigure)
        end
    end
end